classdef LP_Submen < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LP_submenuUIFigure            matlab.ui.Figure
        ApplyButton                   matlab.ui.control.Button
        CancelButton                  matlab.ui.control.Button
        LowPassLabel                  matlab.ui.control.Label
        LowCutoffFrequencyEditFieldLabel  matlab.ui.control.Label
        LowCutoffFrequencyEditField   matlab.ui.control.EditField
        UITable                       matlab.ui.control.Table
        ContextMenu                   matlab.ui.container.ContextMenu
        HelpWhatisaLowPassFilterMenu  matlab.ui.container.Menu
    end

    
    properties (Access = private)
        newx
        newy % Description
        newFs

        CallingApp % Main App Object
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, dat, Fs)
            
            app.CallingApp = mainapp;
            app.newx = dat.Var1;
            app.newy = dat.Var2;
            app.newFs = Fs;
            
            app.UITable.Data = app.newy;
        end

        % Menu selected function: HelpWhatisaLowPassFilterMenu
        function HelpWhatisaLowPassFilterMenuSelected(app, event)
            web('https://en.wikipedia.org/wiki/Low-pass_filter'); %Hmmm this should go to the doc
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            fpass = str2double(app.LowCutoffFrequencyEditField.Value);
            
            ylp = lowpass(app.newy,fpass,app.newFs);
            app.newy=ylp;
            
            updateplot(app.CallingApp,app.newx,app.newy);
            
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LP_submenuUIFigure = uifigure('Visible', 'off');
            app.LP_submenuUIFigure.Name = 'LP_submenu';
            app.ApplyButton = uibutton(app.LP_submenuUIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.CancelButton = uibutton(app.LP_submenuUIFigure, 'push');
            app.CancelButton.Text = 'Cancel';
            app.LowPassLabel = uilabel(app.LP_submenuUIFigure);
            app.LowPassLabel.Text = 'Low-Pass';
            app.LowCutoffFrequencyEditFieldLabel = uilabel(app.LP_submenuUIFigure);
            app.LowCutoffFrequencyEditFieldLabel.Text = 'Low-Cutoff Frequency:';
            app.LowCutoffFrequencyEditField = uieditfield(app.LP_submenuUIFigure, 'text');
            app.UITable = uitable(app.LP_submenuUIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.ContextMenu = uicontextmenu(app.LP_submenuUIFigure);
            % Assign app.ContextMenu
            app.LP_submenuUIFigure.ContextMenu = app.ContextMenu;
            app.HelpWhatisaLowPassFilterMenu = uimenu(app.ContextMenu);
            app.HelpWhatisaLowPassFilterMenu.MenuSelectedFcn = createCallbackFcn(app, @HelpWhatisaLowPassFilterMenuSelected, true);
            app.HelpWhatisaLowPassFilterMenu.Text = '[Help] What is a Low Pass Filter?';
            % Show the figure after all components are created
            app.LP_submenuUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LP_Submen(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LP_submenuUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LP_submenuUIFigure)
        end
    end
end