classdef Res_Submen < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIResamp                 matlab.ui.Figure
        FrequencyEditFieldLabel  matlab.ui.control.Label
        FrequencyEditField       matlab.ui.control.EditField
        MetadataPanel            matlab.ui.container.Panel
        OriginalFsLabel          matlab.ui.control.Label
        OGFsVal                  matlab.ui.control.Label
        ResampleButton           matlab.ui.control.Button
        CancelButton             matlab.ui.control.Button
        UITable                  matlab.ui.control.Table
    end

    
    properties (Access = private)
        CallingApp % Description
        vec_x
        vec_y % X and Y Time and Signal Vectors
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, x, y)
            app.CallingApp = mainapp;
            app.vec_x = x;
            app.vec_y = y;
            app.UITable.Data = [x(:) y(:)];
            
        end

        % Button pushed function: ResampleButton
        function ResampleButtonPushed(app, event)
            Fs = str2double(app.FrequencyEditField.Value);
            
            resy = resample(app.vec_y,app.vec_x,Fs);
            
            resx = 1/Fs:1/Fs:numel(resy)/Fs;
            
            updateplot(app.CallingApp,resx,resy,Fs);
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIResamp = uifigure('Visible', 'off');
            app.UIResamp.Name = 'Resample Signal Vector';
            app.FrequencyEditFieldLabel = uilabel(app.UIResamp);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.UIResamp, 'text');
            app.MetadataPanel = uipanel(app.UIResamp);
            app.MetadataPanel.Title = 'Metadata';
            app.OriginalFsLabel = uilabel(app.MetadataPanel);
            app.OriginalFsLabel.Text = 'Original Fs:';
            app.OGFsVal = uilabel(app.MetadataPanel);
            app.OGFsVal.Text = '';
            app.ResampleButton = uibutton(app.UIResamp, 'push');
            app.ResampleButton.ButtonPushedFcn = createCallbackFcn(app, @ResampleButtonPushed, true);
            app.ResampleButton.Text = 'Resample';
            app.CancelButton = uibutton(app.UIResamp, 'push');
            app.CancelButton.Text = 'Cancel';
            app.UITable = uitable(app.UIResamp);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; ''; ''};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIResamp.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Res_Submen(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIResamp)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIResamp)
        end
    end
end